classdef CollectMaterial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Title           matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        CourseName      matlab.ui.control.DropDown
        Upload          matlab.ui.control.Button
        Label_3         matlab.ui.control.Label
        MaterialType    matlab.ui.control.DropDown
        Label_4         matlab.ui.control.Label
        ReleaseYear     matlab.ui.control.DropDown
        Username        matlab.ui.control.EditField
        UsernameLabel   matlab.ui.control.Label
        UITable         matlab.ui.control.Table
        HashButton      matlab.ui.control.Button
        FileListButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        DsCurriculum
        Curriculum
        FileList
        RepoPath = 'd:\Repo\'; % Description
        CoursePath
        Log = log4m.getLogger;
    end
    
    methods (Access = private)
        
        function UpdateFileList(app,rowNum)
            if exist('rowNum','var')
                app.FileList = cell(rowNum,6);
                app.FileList(:,1) = cellstr(app.Curriculum.Name);
                
            end
            app.FileList = GetFileList(app.FileList,'d:\Repo\',...
                {'教学大纲';'考核材料';'评价报告'});
            app.Log.info('UpdateFileList','更新文件列表');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.ColumnName = {'课程名称','教学大纲','考核材料','评价报告','上传用户','上传日期'}; 
            app.MaterialType.Items = {'';'教学大纲';'考核材料';'评价报告'};
            app.MaterialType.Value = '';
            app.ReleaseYear.Items = {'';'2019';'2021'};
            app.ReleaseYear.Value = '';
            app.Upload.Enable = false;
            app.CourseName.Enable = false;
            app.ReleaseYear.Enable = false;
            app.MaterialType.Enable = false;
            app.HashButton.Visible = false;
            app.FileListButton.Visible = false;
            app.FileListButton.Enable = false;
            app.Log.setFilename('d:\Repo\logfile.txt');
        end

        % Button pushed function: Upload
        function UploadButtonPushed(app, event)
            filter = {'*.*'};
            [file,path] = uigetfile(filter,'选取上传的单个文件','MultiSelect','off');
            source = [path,file];
            [~,~,ext] = fileparts(source);
            fileHash = DataHash(source,'file');
            inf1 = sprintf('%s上传文件%s',app.Username.Value,source);
            app.Log.info('UploadButtonPushed',inf1);
            newFilename = sprintf('《%s》（%s）%s_%s',app.CourseName.Value,...
                app.ReleaseYear.Value,app.MaterialType.Value,fileHash(end-3:end));
            destination = fullfile(app.RepoPath,app.MaterialType.Value,[newFilename,ext]);
            copyfile(source,destination)
            idx = strcmp(app.CourseName.Value,app.FileList(:,1));
            switch app.MaterialType.Value
                case('教学大纲')
                    col = 2;
                case('考核材料')
                    col = 3;
                case('评价报告')
                    col = 4;
            end
            if isempty(app.FileList{idx,col})
                app.FileList{idx,col} = fileHash(end-3:end);
            else
                app.FileList(idx,col) = join([app.FileList(idx,col),{fileHash(end-3:end)}],', ');
            end
            app.FileList{idx,5} = app.Username.Value;
            app.FileList{idx,6} = datestr(now);
            app.UITable.Data(idx,[col,5,6]) = app.FileList(idx,[col,5,6]);
            inf2 = sprintf('%s拷贝文件%s',app.Username.Value,destination);
            app.Log.info('UploadButtonPushed',inf2)
            % 保存FileList
            mask = cellfun(@(x) any(isa(x,'missing')),app.FileList);
            app.FileList(mask) = {[]};
            writecell(app.FileList,'d:\Repo\FileList.xlsx');
            app.Log.info('UploadButtonPushed','保存d:\Repo\FileList.xlsx')
        end

        % Value changed function: CourseName
        function CourseNameValueChanged(app, event)
            value = app.CourseName.Value;
            if isempty(value)
                info = sprintf('%s设定课程名称为空值，当前课程路径为%s',...
                    app.Username.Value,app.RepoPath);
                app.CoursePath = app.RepoPath;
                app.MaterialType.Enable = false;
            else
                app.CoursePath = [app.RepoPath,value,'\'];
                if exist(app.CoursePath,'dir') == 0
                    mkdir(app.CoursePath)
                    mkdir(app.CoursePath,'教学大纲\')
                    mkdir(app.CoursePath,'考核材料\')
                    mkdir(app.CoursePath,'评价报告\')
                    info = sprintf('%s创建目录%s',app.Username.Value,app.CoursePath);
                else
                    info = sprintf('%s设定当前目录%s',app.Username.Value,app.CoursePath);
                end
                app.MaterialType.Enable = true;
            end
            app.Log.info('CourseNameValueChanged',info)
            app.Upload.Enable = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.CourseName.Value)
                app.Log.info('UIFigureCloseRequest','关闭程序');
                if iscell(app.FileList)
                    mask = cellfun(@(x) any(isa(x,'missing')),app.FileList);
                    app.FileList(mask) = {[]};
                    writecell(app.FileList,'d:\Repo\FileList.xlsx');
                    app.Log.info('UIFigureCloseRequest','保存FileList后关闭程序');
                end
            end           
            delete(app)
        end

        % Value changed function: Username
        function UsernameValueChanged(app, event)
            value = app.Username.Value;
            adminList = {'关国强'};
            app.ReleaseYear.Enable = true;
            if any(strcmp(value,adminList))
                app.HashButton.Visible = true;
                app.FileListButton.Visible = true;
                info = sprintf('管理员用户设为%s',value);
            else
                app.HashButton.Visible = false;
                app.FileListButton.Visible = false;
                info = sprintf('用户设为%s',value);
            end
            app.Log.info('UsernameValueChanged',info)
        end

        % Value changed function: ReleaseYear
        function ReleaseYearValueChanged(app, event)
            value = app.ReleaseYear.Value;
            if isempty(value)
                app.Log.info('ReleaseYearValueChanged',...
                    sprintf('%s设定培养方案年份为空值',app.Username.Value));
                app.CourseName.Enable = false;
                app.FileListButton.Enable = false;
            else
                app.Log.info('ReleaseYearValueChanged',...
                    sprintf('%s设定培养方案年份为%s，载入相应的培养方案',...
                    app.Username.Value,value));
                app.DsCurriculum = sprintf('db_Curriculum%s',value);
                load('database.mat',app.DsCurriculum)
                app.Curriculum = eval(app.DsCurriculum);
                idx = strcmp([app.Curriculum.Remark],"提交备案");
                app.Curriculum = app.Curriculum(idx,:);
                app.CourseName.Items = ["";app.Curriculum.Name];
                app.CourseName.Value = "";
                app.CourseName.Enable = true;
                filePath = fullfile(app.RepoPath,'FileList.xlsx');
                if exist(filePath,'file') == 2    
                    app.FileList = readcell(filePath);
                    mask = cellfun(@(x) any(isa(x,'missing')),app.FileList);
                    app.FileList(mask) = {[]};
                else
                    UpdateFileList(app,sum(idx)); % 输入第2个参数将新建app.FileList
                end
                app.FileListButton.Enable = true; % 仅当已载入app.FileList后才可更新
                app.UITable.Data = app.FileList;
            end
        end

        % Button pushed function: HashButton
        function HashButtonPushed(app, event)
            info = ChkFileHash(app.RepoPath);
            app.Log.info('HashButtonPushed',info);
            app.Log.info('HashButtonPushed','调用UpdateFileList()');
            UpdateFileList(app);
        end

        % Button pushed function: FileListButton
        function FileListButtonPushed(app, event)
            % 当已载入app.FileList后
            filePath = fullfile(app.RepoPath,'FileList.xlsx');
            app.Log.info('FileListButtonPushed','调用UpdateFileList()');
            UpdateFileList(app);
            app.UITable.Data = app.FileList;
            app.Log.info('FileListButtonPushed',sprintf('更新文件%s',filePath));
            writecell(app.FileList,filePath);
        end

        % Value changed function: MaterialType
        function MaterialTypeValueChanged(app, event)
            value = app.MaterialType.Value;
            if isempty(value)
                app.Upload.Enable = false;
            else
                app.Upload.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '课程教学原始材料收集程序';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '课程名称';
            app.CourseName = uidropdown(app.UIFigure);
            app.CourseName.ValueChangedFcn = createCallbackFcn(app, @CourseNameValueChanged, true);
            app.Upload = uibutton(app.UIFigure, 'push');
            app.Upload.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.Upload.Text = '上传';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '文件类型';
            app.MaterialType = uidropdown(app.UIFigure);
            app.MaterialType.Items = {'教学大纲', '考核材料', '评价报告'};
            app.MaterialType.ValueChangedFcn = createCallbackFcn(app, @MaterialTypeValueChanged, true);
            app.MaterialType.Value = '教学大纲';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '方案年度';
            app.ReleaseYear = uidropdown(app.UIFigure);
            app.ReleaseYear.Items = {'2019', '2021'};
            app.ReleaseYear.ValueChangedFcn = createCallbackFcn(app, @ReleaseYearValueChanged, true);
            app.ReleaseYear.Value = '2021';
            app.Username = uieditfield(app.UIFigure, 'text');
            app.Username.ValueChangedFcn = createCallbackFcn(app, @UsernameValueChanged, true);
            app.UsernameLabel = uilabel(app.UIFigure);
            app.UsernameLabel.Text = '用户姓名';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'课程'; '教学大纲'; '考核材料'; '评价报告'; '提交用户'; '提交日期'};
            app.UITable.RowName = {};
            app.HashButton = uibutton(app.UIFigure, 'push');
            app.HashButton.ButtonPushedFcn = createCallbackFcn(app, @HashButtonPushed, true);
            app.HashButton.Text = '更新Hash';
            app.FileListButton = uibutton(app.UIFigure, 'push');
            app.FileListButton.ButtonPushedFcn = createCallbackFcn(app, @FileListButtonPushed, true);
            app.FileListButton.Text = '更新FileList';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CollectMaterial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end