classdef Syllabus_genDoc_UI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SyllabusAssistantUIFigure  matlab.ui.Figure
        LabelHeadTitle             matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Code                       matlab.ui.control.EditField
        Label_4                    matlab.ui.control.Label
        Type                       matlab.ui.control.EditField
        Label_5                    matlab.ui.control.Label
        CompulsoryOrNot            matlab.ui.control.EditField
        Label_6                    matlab.ui.control.Label
        Hours                      matlab.ui.control.EditField
        Label_7                    matlab.ui.control.Label
        Credit                     matlab.ui.control.EditField
        Label_8                    matlab.ui.control.Label
        Semester                   matlab.ui.control.EditField
        Label_9                    matlab.ui.control.Label
        Language                   matlab.ui.control.EditField
        Label_10                   matlab.ui.control.Label
        SelectPrerequisites        matlab.ui.control.ListBox
        Label_11                   matlab.ui.control.Label
        Name                       matlab.ui.control.DropDown
        SaveData                   matlab.ui.control.Button
        DispPrerequisites          matlab.ui.control.TextArea
        Label_12                   matlab.ui.control.Label
        SaveFilePath               matlab.ui.control.EditField
        SelectFile                 matlab.ui.control.StateButton
        TabGroup                   matlab.ui.container.TabGroup
        Tab1                       matlab.ui.container.Tab
        TabReq2Obj                 matlab.ui.control.Table
        Tab2                       matlab.ui.container.Tab
        Description                matlab.ui.control.TextArea
        Tab3                       matlab.ui.container.Tab
        Content                    matlab.ui.control.TextArea
        Tab4                       matlab.ui.container.Tab
        ExpTeach                   matlab.ui.control.TextArea
        Tab5                       matlab.ui.container.Tab
        TeachMethod                matlab.ui.control.TextArea
        Tab6                       matlab.ui.container.Tab
        ExamMethod                 matlab.ui.control.TextArea
        Benchmark                  matlab.ui.control.Table
        Tab7                       matlab.ui.container.Tab
        Textbook                   matlab.ui.control.TextArea
        LoadData                   matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        Label_13                   matlab.ui.control.Label
        Info                       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        DsCurriculum = 'db_Curriculum2021a'; % 课程体系描述字段
        DsIndicators = 'db_Indicators2021'; % 毕业要求指标点（内涵观测点）
        Curriculum;
        Indicators;
        DefaultSavePath = '';
        TemplatePath = 'C:\Users\gqgua\Documents\Git\EnergyChE\SyllabusAssistant\res\syllabus.dotx';
        CurrentCourse Course
        AppInfo;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DefaultSavePath = cd;
            app.SaveFilePath.Value = app.DefaultSavePath;
            load('database.mat',app.DsCurriculum,app.DsIndicators);
            app.Curriculum = eval(app.DsCurriculum);
            app.Indicators = eval(app.DsIndicators);
            app.Name.Items = app.Curriculum.Name;
            app.SelectPrerequisites.Items = app.Curriculum.Name;
            if exist('app.mat','file') ~= 2
                app.LoadData.Enable = false;
            end
            if exist(app.TemplatePath,'file') == 2
                app.Info.Value = app.TemplatePath;
            else
                app.Info.Value = sprintf('找不到文件"%s"！',app.TemplatePath);
            end
            app.SaveData.Enable = false;
            info3 = DataHash(app.DsCurriculum);
            info2 = DataHash(app.DsIndicators);
            info1 = '修订版v1.6';
            app.AppInfo = strcat('（',info1,'@',...
                              '指标点',info2(end-3:end),...
                              '、支撑矩阵',info3(end-3:end),'）');
            app.SyllabusAssistantUIFigure.Name = ...
                strcat(app.SyllabusAssistantUIFigure.Name,app.AppInfo);
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            app.CurrentCourse = Course(app.Name.Value);
            iCourse = strcmp(app.Name.Value,app.Curriculum.Name);
            app.Code.Value = app.Curriculum.IDv2018(iCourse);
            app.CurrentCourse.Code = app.Code.Value;
            app.Type.Value = app.Curriculum.Type(iCourse);
            app.CurrentCourse.Category = app.Type.Value;
            app.CompulsoryOrNot.Value = "必修";
            app.CurrentCourse.CompulsoryOrNot = true;
            app.Credit.Value = string(app.Curriculum.Credit(iCourse));
            app.CurrentCourse.Credits = app.Credit.Value;
            app.Hours.Value = string(double(app.Curriculum.Credit(iCourse))*16);
            app.CurrentCourse.ClassHour = app.Hours.Value;
            app.Semester.Value = app.Curriculum.Semester(iCourse);
            app.CurrentCourse.Semester = app.Semester.Value;
            app.Language.Value = "中文";
            app.CurrentCourse.Language = app.Language.Value;
            % 列出该课程支撑的毕业要求指标点
            app.TabReq2Obj.ColumnEditable = [false true];
            idx = logical(app.Curriculum.ReqMatrix(iCourse,:));
            indicators = app.Indicators{idx,:};
            dat1 = cell(size(indicators,1),2);
            for i = 1:size(indicators,1)
                dat1{i,1} = sprintf('%s %s',indicators{i,1},indicators{i,2});
            end
            app.TabReq2Obj.Data = dat1;
            app.CurrentCourse.Outcomes = dat1(:,1);
            dat2 = cell(size(indicators,1),6);
            dat2(:,1) = cellstr(arrayfun(@(x)strcat('[o',x,']'),string((1:size(indicators,1))')));
            app.Benchmark.Data = dat2;
        end

        % Button pushed function: SaveData
        function SaveDataPushed(app, event)
            [file,path] = uiputfile(strcat(app.CurrentCourse.Title,'.docx'));
            app.Info.Value = sprintf('在“%s”生成文件“%s”',path,file);
            app.CurrentCourse.FilePath = strcat(path,file);
            cc = app.CurrentCourse;
            save('app.mat','cc')
            TemplateFile = strcat(app.TemplatePath,'\syllabus.dotx');
            app.Info.Value = Syllabus_genDoc(cc,TemplateFile,app.AppInfo);
%             app.delete
        end

        % Value changed function: SelectPrerequisites
        function SelectPrerequisitesValueChanged(app, event)
            value = app.SelectPrerequisites.Value;
            if ismissing(app.DispPrerequisites.Value)
                app.DispPrerequisites.Value = value;
            else
                idx = strcmp(value,app.DispPrerequisites.Value);
                if any(idx)
                    app.DispPrerequisites.Value(idx) = [];
                else
                    app.DispPrerequisites.Value = vertcat(app.DispPrerequisites.Value,value);
                end               
            end
            app.CurrentCourse.Prerequisites = app.DispPrerequisites.Value;
        end

        % Value changed function: SelectFile
        function SelectFileValueChanged(app, event)
            [~,app.TemplatePath] = uigetfile('syllabus.dotx');
            if ischar(app.DefaultSavePath)
                app.SaveFilePath.Value = app.TemplatePath;
                app.SaveData.Enable = true;
            end
        end

        % Value changed function: SaveFilePath
        function SaveFilePathValueChanged(app, event)
            app.DefaultSavePath = app.SaveFilePath.Value;
            app.SaveData.Enable = true;
        end

        % Cell edit callback: TabReq2Obj
        function TabReq2ObjCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.Objectives{indices(1),1} = newData;
        end

        % Value changed function: Description
        function DescriptionValueChanged(app, event)
            value = app.Description.Value;
            app.CurrentCourse.Description = value;
        end

        % Value changed function: Content
        function ContentValueChanged(app, event)
            value = app.Content.Value;
            app.CurrentCourse.Content = value;
        end

        % Value changed function: ExpTeach
        function ExpTeachValueChanged(app, event)
            value = app.ExpTeach.Value;
            app.CurrentCourse.ExpTeach = value;
        end

        % Value changed function: TeachMethod
        function TeachMethodValueChanged(app, event)
            value = app.TeachMethod.Value;
            app.CurrentCourse.TeachMethod = value;
        end

        % Value changed function: ExamMethod
        function ExamMethodValueChanged(app, event)
            value = app.ExamMethod.Value;
            app.CurrentCourse.ExamMethod = value;
        end

        % Value changed function: Textbook
        function TextbookValueChanged(app, event)
            value = app.Textbook.Value;
            app.CurrentCourse.Textbook = value;
        end

        % Button pushed function: LoadData
        function LoadDataPushed(app, event)
            load('app.mat','cc')
            app.CurrentCourse = cc;
            app.Code.Value = cc.Code;
            app.Type.Value = cc.Category;
            if cc.CompulsoryOrNot
                app.CompulsoryOrNot.Value = "必修";
            else
                app.CompulsoryOrNot.Value = "选修";
            end
            app.Hours.Value = cc.ClassHour;
            app.Credit.Value = cc.Credits;
            app.Semester.Value = cc.Credits;
            app.Language.Value = cc.Language;
            app.Name.Value = cc.Title;
            app.DispPrerequisites.Value = cc.Prerequisites;            
            app.TabReq2Obj.Data = horzcat(cc.Outcomes,cc.Objectives);
            app.Description.Value = cc.Description;
            app.Content.Value = cc.Content;
            app.ExpTeach.Value = cc.ExpTeach;
            app.TeachMethod.Value = cc.TeachMethod;
            app.ExamMethod.Value = cc.ExamMethod;
            app.Textbook.Value = cc.Textbook;
            %
            NObj = size(cc.Objectives,1);
            dat2 = cell(NObj,6);
            dat2(:,1) = cellstr(arrayfun(@(x)strcat('[o',x,']'),string((1:NObj)')));
            app.Benchmark.Data = dat2;
            if ~isempty(cc.Benchmark)
                app.Benchmark.Data = cc.Benchmark;
            end
            app.CurrentCourse.Benchmark = app.Benchmark.Data;
        end

        % Cell edit callback: Benchmark
        function BenchmarkCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.CurrentCourse.Benchmark{indices(1),indices(2)} = newData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SyllabusAssistantUIFigure = uifigure('Visible', 'off');
            app.SyllabusAssistantUIFigure.Name = 'Syllabus Assistant';
            app.LabelHeadTitle = uilabel(app.SyllabusAssistantUIFigure);
            app.LabelHeadTitle.Text = '课程教学大纲文档生成器';
            app.Label_3 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_3.Text = '课程代码';
            app.Code = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Code.Editable = 'off';
            app.Label_4 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_4.Text = '课程类型';
            app.Type = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Type.Editable = 'off';
            app.Label_5 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_5.Text = '课程性质';
            app.CompulsoryOrNot = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.CompulsoryOrNot.Editable = 'off';
            app.Label_6 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_6.Text = '课程学时';
            app.Hours = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Hours.Editable = 'off';
            app.Label_7 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_7.Text = '课程学分';
            app.Credit = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Credit.Editable = 'off';
            app.Label_8 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_8.Text = '开课学期';
            app.Semester = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Semester.Editable = 'off';
            app.Label_9 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_9.Text = '授课语言';
            app.Language = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.Language.Editable = 'off';
            app.Label_10 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_10.Text = '先修课程';
            app.SelectPrerequisites = uilistbox(app.SyllabusAssistantUIFigure);
            app.SelectPrerequisites.Multiselect = 'on';
            app.SelectPrerequisites.ValueChangedFcn = createCallbackFcn(app, @SelectPrerequisitesValueChanged, true);
            app.SelectPrerequisites.Tooltip = {'点选多门先修课程并列于右表；若该课程已列于右表，则删除该课程。'};
            app.SelectPrerequisites.Value = {'Item 1'};
            app.Label_11 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_11.Text = '课程名称';
            app.Name = uidropdown(app.SyllabusAssistantUIFigure);
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.SaveData = uibutton(app.SyllabusAssistantUIFigure, 'push');
            app.SaveData.ButtonPushedFcn = createCallbackFcn(app, @SaveDataPushed, true);
            app.SaveData.Text = '生成Word模板';
            app.DispPrerequisites = uitextarea(app.SyllabusAssistantUIFigure);
            app.DispPrerequisites.Editable = 'off';
            app.Label_12 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_12.Text = '大纲文件路径';
            app.SaveFilePath = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            app.SaveFilePath.ValueChangedFcn = createCallbackFcn(app, @SaveFilePathValueChanged, true);
            app.SelectFile = uibutton(app.SyllabusAssistantUIFigure, 'state');
            app.SelectFile.ValueChangedFcn = createCallbackFcn(app, @SelectFileValueChanged, true);
            app.SelectFile.Text = '选择';
            app.TabGroup = uitabgroup(app.SyllabusAssistantUIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '课程目标';
            app.TabReq2Obj = uitable(app.Tab1);
            app.TabReq2Obj.ColumnName = {'课程支撑的毕业要求'; '课程教学目标'};
            app.TabReq2Obj.RowName = {};
            app.TabReq2Obj.CellEditCallback = createCallbackFcn(app, @TabReq2ObjCellEdit, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = '课程简介';
            app.Description = uitextarea(app.Tab2);
            app.Description.ValueChangedFcn = createCallbackFcn(app, @DescriptionValueChanged, true);
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = '教学内容';
            app.Content = uitextarea(app.Tab3);
            app.Content.ValueChangedFcn = createCallbackFcn(app, @ContentValueChanged, true);
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = '实践教学';
            app.ExpTeach = uitextarea(app.Tab4);
            app.ExpTeach.ValueChangedFcn = createCallbackFcn(app, @ExpTeachValueChanged, true);
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = '教学方法';
            app.TeachMethod = uitextarea(app.Tab5);
            app.TeachMethod.ValueChangedFcn = createCallbackFcn(app, @TeachMethodValueChanged, true);
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = '考核方式';
            app.ExamMethod = uitextarea(app.Tab6);
            app.ExamMethod.ValueChangedFcn = createCallbackFcn(app, @ExamMethodValueChanged, true);
            app.Benchmark = uitable(app.Tab6);
            app.Benchmark.ColumnName = {'#'; '优秀'; '良好'; '中等'; '合格'; '不合格'};
            app.Benchmark.RowName = {};
            app.Benchmark.ColumnEditable = [false true true true true true];
            app.Benchmark.CellEditCallback = createCallbackFcn(app, @BenchmarkCellEdit, true);
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = '教材资料';
            app.Textbook = uitextarea(app.Tab7);
            app.Textbook.ValueChangedFcn = createCallbackFcn(app, @TextbookValueChanged, true);
            app.LoadData = uibutton(app.SyllabusAssistantUIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataPushed, true);
            app.LoadData.Text = '载入课程数据';
            app.Image = uiimage(app.SyllabusAssistantUIFigure);
            app.Image.ImageSource = 'bedge_small.png';
            app.Label_13 = uilabel(app.SyllabusAssistantUIFigure);
            app.Label_13.Text = '文件生成信息';
            app.Info = uieditfield(app.SyllabusAssistantUIFigure, 'text');
            % Show the figure after all components are created
            app.SyllabusAssistantUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Syllabus_genDoc_UI_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SyllabusAssistantUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SyllabusAssistantUIFigure)
        end
    end
end